classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        ConvolutionTab          matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        ChooseFileButton        matlab.ui.control.Button
        FileEditField           matlab.ui.control.EditField
        FileLabel               matlab.ui.control.Label
        Histogram               matlab.ui.control.UIAxes
        SpatialSmoothingTab     matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        TabGroup2               matlab.ui.container.TabGroup
        ImageBrighteningTab     matlab.ui.container.Tab
        bEditField              matlab.ui.control.NumericEditField
        aEditField              matlab.ui.control.NumericEditField
        ApplyButton             matlab.ui.control.Button
        bSlider                 matlab.ui.control.Slider
        bSliderLabel            matlab.ui.control.Label
        aSlider                 matlab.ui.control.Slider
        aSliderLabel            matlab.ui.control.Label
        TransformasiLogTab      matlab.ui.container.Tab
        cSlider                 matlab.ui.control.Slider
        cSliderLabel            matlab.ui.control.Label
        ApplyButton_2           matlab.ui.control.Button
        TransformasiPangkatTab  matlab.ui.container.Tab
        gammaSlider             matlab.ui.control.Slider
        gammaSliderLabel        matlab.ui.control.Label
        cSlider_2               matlab.ui.control.Slider
        cSlider_2Label          matlab.ui.control.Label
        ApplyButton_3           matlab.ui.control.Button
        PereganganKontrasTab    matlab.ui.container.Tab
        OutputLabel             matlab.ui.control.Label
        InputLabel              matlab.ui.control.Label
        FileEditField_2         matlab.ui.control.EditField
        FileEditField_2Label    matlab.ui.control.Label
        ChooseImageButton       matlab.ui.control.Button
        EnhancementOutImage     matlab.ui.control.Image
        EnhancementInImage      matlab.ui.control.Image
        EnhancementInHist       matlab.ui.control.UIAxes
        EnhancementOutHist      matlab.ui.control.UIAxes
        FrequencySmoothingTab   matlab.ui.container.Tab
        GridLayout3_2           matlab.ui.container.GridLayout
        OutputLabel_3           matlab.ui.control.Label
        EqualizationOutImage    matlab.ui.control.Image
        InputLabel_2            matlab.ui.control.Label
        FileEditField_3         matlab.ui.control.EditField
        FileEditField_3Label    matlab.ui.control.Label
        ChooseImageButton_2     matlab.ui.control.Button
        EqualizationInImage     matlab.ui.control.Image
        EqualizationInHist      matlab.ui.control.UIAxes
        EqualizationOutHist     matlab.ui.control.UIAxes
        HighBoostingTab         matlab.ui.container.Tab
        GridLayout4             matlab.ui.container.GridLayout
        LowPassImage            matlab.ui.control.Image
        HighBoostEditField      matlab.ui.control.NumericEditField
        HighBoostImage          matlab.ui.control.Image
        SourceImage             matlab.ui.control.Image
        SourceEditField         matlab.ui.control.EditField
        HighBoostButton         matlab.ui.control.Button
        SourceImageButton       matlab.ui.control.Button
        MedianFilteringTab      matlab.ui.container.Tab
        GridLayout5             matlab.ui.container.GridLayout
        ResultImageButton       matlab.ui.control.Button
        SaltPepperButton        matlab.ui.control.Button
        NoiseImageButton        matlab.ui.control.Button
        SaltPepperEditField     matlab.ui.control.NumericEditField
        NoiseEditField          matlab.ui.control.EditField
        MedianImage             matlab.ui.control.Image
        NoiseImage              matlab.ui.control.Image
        ClearImage              matlab.ui.control.Image
        ClearEditField          matlab.ui.control.EditField
        ClearImageButton        matlab.ui.control.Button
        Tab                     matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField.Value = fullpath;
                im = imread(fullpath);
                [x, bins] = histog(im);
                bar(app.Histogram, x, bins);
            end
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_2.Value = fullpath;
                im = imread(fullpath);
                app.EnhancementInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EnhancementInHist, x, bins);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = brighten(im, app.aSlider.Value, app.bSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = logtran(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = exp(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            value = app.bSlider.Value;
            app.bEditField.Value = value;
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.bSlider.Value = value;
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            app.aSlider.Value = value;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.aSlider.Value = value;
        end

        % Button pushed function: ChooseImageButton_2
        function ChooseImageButton_2Pushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_3.Value = fullpath;
                im = imread(fullpath);
                app.EqualizationInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EqualizationInHist, x, bins);

                im_equalized = histog_equalization(im);
                app.EqualizationOutImage.ImageSource = im_equalized;
                [x, bins] = histog(im_equalized);
                bar(app.EqualizationOutHist, x, bins);
            end
        end

        % Button pushed function: SourceImageButton
        function SourceImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SourceEditField.Value = fullpath;
                im = imread(fullpath);
                im = gray_to_rgb(im);
                app.SourceImage.ImageSource = im;
                app.LowPassImage.ImageSource = uint8(low_pass(im));
            end
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            im = imread(app.SourceEditField.Value);
            im = gray_to_rgb(im);
            app.HighBoostImage.ImageSource = high_boost(im, app.HighBoostEditField.Value);
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.ClearEditField.Value = fullpath;
                im = imread(fullpath);
                app.ClearImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: NoiseImageButton
        function NoiseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.NoiseEditField.Value = fullpath;
                im = imread(fullpath);
                app.NoiseImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            im = app.ClearImage.ImageSource;
            app.NoiseImage.ImageSource = salt_pepper_noise(im, app.SaltPepperEditField.Value);
        end

        % Button pushed function: ResultImageButton
        function ResultImageButtonPushed(app, event)
            im = app.NoiseImage.ImageSource;
            app.MedianImage.ImageSource = median_filter(im);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.GridLayout2 = uigridlayout(app.ConvolutionTab);
            app.Histogram = uiaxes(app.GridLayout2);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, 'Gray Level')
            ylabel(app.Histogram, 'Frequency')
            app.Histogram.Tag = 'HistogramDisplay';
            app.FileLabel = uilabel(app.GridLayout2);
            app.FileLabel.Text = 'File: ';
            app.FileEditField = uieditfield(app.GridLayout2, 'text');
            app.ChooseFileButton = uibutton(app.GridLayout2, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.SpatialSmoothingTab = uitab(app.TabGroup);
            app.SpatialSmoothingTab.Title = 'Spatial Smoothing';
            app.GridLayout3 = uigridlayout(app.SpatialSmoothingTab);
            app.EnhancementOutHist = uiaxes(app.GridLayout3);
            title(app.EnhancementOutHist, 'Title')
            xlabel(app.EnhancementOutHist, 'X')
            ylabel(app.EnhancementOutHist, 'Y')
            zlabel(app.EnhancementOutHist, 'Z')
            app.EnhancementInHist = uiaxes(app.GridLayout3);
            title(app.EnhancementInHist, 'Title')
            xlabel(app.EnhancementInHist, 'X')
            ylabel(app.EnhancementInHist, 'Y')
            zlabel(app.EnhancementInHist, 'Z')
            app.EnhancementInImage = uiimage(app.GridLayout3);
            app.EnhancementOutImage = uiimage(app.GridLayout3);
            app.ChooseImageButton = uibutton(app.GridLayout3, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.FileEditField_2Label = uilabel(app.GridLayout3);
            app.FileEditField_2Label.Text = 'File:';
            app.FileEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.InputLabel = uilabel(app.GridLayout3);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout3);
            app.OutputLabel.Text = 'Output';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.ImageBrighteningTab = uitab(app.TabGroup2);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.aSliderLabel = uilabel(app.ImageBrighteningTab);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.ImageBrighteningTab);
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.Value = 1;
            app.bSliderLabel = uilabel(app.ImageBrighteningTab);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.ImageBrighteningTab);
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.ApplyButton = uibutton(app.ImageBrighteningTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.aEditField = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditField = uieditfield(app.ImageBrighteningTab, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.TransformasiLogTab = uitab(app.TabGroup2);
            app.TransformasiLogTab.Title = 'Transformasi Log';
            app.ApplyButton_2 = uibutton(app.TransformasiLogTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.cSliderLabel = uilabel(app.TransformasiLogTab);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.TransformasiLogTab);
            app.cSlider.Value = 1;
            app.TransformasiPangkatTab = uitab(app.TabGroup2);
            app.TransformasiPangkatTab.Title = 'Transformasi Pangkat';
            app.ApplyButton_3 = uibutton(app.TransformasiPangkatTab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.cSlider_2Label = uilabel(app.TransformasiPangkatTab);
            app.cSlider_2Label.Text = 'c';
            app.cSlider_2 = uislider(app.TransformasiPangkatTab);
            app.cSlider_2.Value = 1;
            app.gammaSliderLabel = uilabel(app.TransformasiPangkatTab);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.TransformasiPangkatTab);
            app.PereganganKontrasTab = uitab(app.TabGroup2);
            app.PereganganKontrasTab.Title = 'Peregangan Kontras';
            app.FrequencySmoothingTab = uitab(app.TabGroup);
            app.FrequencySmoothingTab.Title = 'Frequency Smoothing';
            app.GridLayout3_2 = uigridlayout(app.FrequencySmoothingTab);
            app.EqualizationOutHist = uiaxes(app.GridLayout3_2);
            title(app.EqualizationOutHist, 'Title')
            xlabel(app.EqualizationOutHist, 'X')
            ylabel(app.EqualizationOutHist, 'Y')
            zlabel(app.EqualizationOutHist, 'Z')
            app.EqualizationInHist = uiaxes(app.GridLayout3_2);
            title(app.EqualizationInHist, 'Title')
            xlabel(app.EqualizationInHist, 'X')
            ylabel(app.EqualizationInHist, 'Y')
            zlabel(app.EqualizationInHist, 'Z')
            app.EqualizationInImage = uiimage(app.GridLayout3_2);
            app.ChooseImageButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.ChooseImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButton_2Pushed, true);
            app.ChooseImageButton_2.Text = 'Choose Image';
            app.FileEditField_3Label = uilabel(app.GridLayout3_2);
            app.FileEditField_3Label.Text = 'File:';
            app.FileEditField_3 = uieditfield(app.GridLayout3_2, 'text');
            app.InputLabel_2 = uilabel(app.GridLayout3_2);
            app.InputLabel_2.Text = 'Input';
            app.EqualizationOutImage = uiimage(app.GridLayout3_2);
            app.OutputLabel_3 = uilabel(app.GridLayout3_2);
            app.OutputLabel_3.Text = 'Output';
            app.HighBoostingTab = uitab(app.TabGroup);
            app.HighBoostingTab.Title = 'High Boosting';
            app.GridLayout4 = uigridlayout(app.HighBoostingTab);
            app.SourceImageButton = uibutton(app.GridLayout4, 'push');
            app.SourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @SourceImageButtonPushed, true);
            app.SourceImageButton.Text = 'Choose Image';
            app.HighBoostButton = uibutton(app.GridLayout4, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'Apply Highboost coefficient';
            app.SourceEditField = uieditfield(app.GridLayout4, 'text');
            app.SourceImage = uiimage(app.GridLayout4);
            app.HighBoostImage = uiimage(app.GridLayout4);
            app.HighBoostEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HighBoostEditField.Value = 2.5;
            app.LowPassImage = uiimage(app.GridLayout4);
            app.MedianFilteringTab = uitab(app.TabGroup);
            app.MedianFilteringTab.Title = 'Median Filtering';
            app.GridLayout5 = uigridlayout(app.MedianFilteringTab);
            app.ClearImageButton = uibutton(app.GridLayout5, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Choose Image Source';
            app.ClearEditField = uieditfield(app.GridLayout5, 'text');
            app.ClearImage = uiimage(app.GridLayout5);
            app.NoiseImage = uiimage(app.GridLayout5);
            app.MedianImage = uiimage(app.GridLayout5);
            app.NoiseEditField = uieditfield(app.GridLayout5, 'text');
            app.SaltPepperEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SaltPepperEditField.Value = 0.05;
            app.NoiseImageButton = uibutton(app.GridLayout5, 'push');
            app.NoiseImageButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseImageButtonPushed, true);
            app.NoiseImageButton.Text = 'Choose Image Noise';
            app.SaltPepperButton = uibutton(app.GridLayout5, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'or Apply Salt Pepper from Source';
            app.ResultImageButton = uibutton(app.GridLayout5, 'push');
            app.ResultImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultImageButtonPushed, true);
            app.ResultImageButton.Text = 'Filter the Image Noise!';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end